classdef menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainSystemTab                  matlab.ui.container.Tab
        RunSimulinkButton              matlab.ui.control.Button
        StopTimeEditFieldLabel         matlab.ui.control.Label
        StopTimeEditField              matlab.ui.control.NumericEditField
        LibraryTab                     matlab.ui.container.Tab
        AddSensorButton                matlab.ui.control.Button
        AddActuatorButton              matlab.ui.control.Button
        AvailableSensorsLabel          matlab.ui.control.Label
        AvailableSensorsDropDown       matlab.ui.control.DropDown
        AvailableActuatorsDropDownLabel  matlab.ui.control.Label
        AvailableActuatorsDropDown     matlab.ui.control.DropDown
        Tree                           matlab.ui.container.Tree
        SensorsNode                    matlab.ui.container.TreeNode
        Tree_2                         matlab.ui.container.Tree
        ActuatorsNode                  matlab.ui.container.TreeNode
        Magnetorquer1Node              matlab.ui.container.TreeNode
        Magnetorquer2Node              matlab.ui.container.TreeNode
        Magnetorquer3Node              matlab.ui.container.TreeNode
        MomentumWheel1Node             matlab.ui.container.TreeNode
        EditButton                     matlab.ui.control.Button
        RemoveButton                   matlab.ui.control.Button
        AlgorithmsTab                  matlab.ui.container.Tab
        AvailableDeterminationAlgorithmsLabel  matlab.ui.control.Label
        AvailableControlAlgorithmsLabel  matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        DropDown_2                     matlab.ui.control.DropDown
        AddDeterminationAlgorithmButton  matlab.ui.control.Button
        AddControlAlgorithmButton      matlab.ui.control.Button
        ViewDeterminationAlgorithmButton  matlab.ui.control.Button
        ViewDeterminationAlgorithmButton_2  matlab.ui.control.Button
        ErrorTestingTab                matlab.ui.container.Tab
        PropagateTab                   matlab.ui.container.Tab
        IntegratorDropDownLabel        matlab.ui.control.Label
        IntegratorDropDown             matlab.ui.control.DropDown
        InitialtimestepEditFieldLabel  matlab.ui.control.Label
        InitialtimestepEditField       matlab.ui.control.NumericEditField
        timestepEditFieldLabel         matlab.ui.control.Label
        timestepEditField              matlab.ui.control.NumericEditField
        timeintervalEditFieldLabel     matlab.ui.control.Label
        timeintervalEditField          matlab.ui.control.NumericEditField
        StartEpochEditFieldLabel       matlab.ui.control.Label
        StartEpochEditField            matlab.ui.control.NumericEditField
        EpochFormatDropDownLabel       matlab.ui.control.Label
        EpochFormatDropDown            matlab.ui.control.DropDown
        secLabel                       matlab.ui.control.Label
        secLabel_2                     matlab.ui.control.Label
        secLabel_3                     matlab.ui.control.Label
        PropagateButton                matlab.ui.control.Button
        ViewTab                        matlab.ui.container.Tab
        rowanglevstimeCheckBox         matlab.ui.control.CheckBox
        pitchanglevstimeCheckBox       matlab.ui.control.CheckBox
        yawanglevstimeCheckBox         matlab.ui.control.CheckBox
        pitcherrorvstimeCheckBox_2     matlab.ui.control.CheckBox
        rowerrorvstimeCheckBox         matlab.ui.control.CheckBox
        yawerrorvstimeCheckBox         matlab.ui.control.CheckBox
        rowerrorvstimemaxsensorerrorCheckBox  matlab.ui.control.CheckBox
        pitcherrorvstimemaxsensorerrorCheckBox  matlab.ui.control.CheckBox
        yawerrorvstimemaxsensorerrorCheckBox  matlab.ui.control.CheckBox
        PlotLabel                      matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ReferenceFrameViewDropDownLabel  matlab.ui.control.Label
        ReferenceFrameViewDropDown     matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        n_2 = 1; % Description
        n_F_SS = 1;
        n_Magnetometer = 1;
        n_Gyro = 1;
        stop_time;
        
        n = 1;
        
        C_SS_str;
        F_SS_str;
        Magnetometer_str;
        Gyro_str;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %------------------------------------------------------
            %--------------------- MUDAR ISTO ---------------------
            %------------------------------------------------------
            evalin('base','clear')
            clc
            addpath('D:\Universidade\Engenharia Aeroespacial\5º Ano\1º Semestre\Projecto Aeroespacial\Projeto\Documentos\Matlab Zeno\ADCS_simulink_mod')                           
            
            app.stop_time = app.StopTimeEditField.Value;
        end

        % Button pushed function: AddSensorButton
        function AddSensorButtonPushed(app, event)
            switch app.AvailableSensorsDropDown.Value
                case 'Coarse Sun Sensor'
                    
                    str = app.SensorsNode.Children;                   
                    while app.n <= size(str,1)
                        str_text = str(app.n).Text(1:end-2);
                        str_num = str(app.n).Text(end);
                        if strcmp(str_text,'Coarse Sun Sersor')==1 && strcmp(str_num,num2str(app.n_2))==1
                            app.n_2 = app.n_2 + 1;
                            app.n = 1;                       
                        else
                        app.n = app.n + 1;
                        end
                    end
                    app.C_SS_str = ['Coarse Sun Sersor ', num2str(app.n_2)];
                    
                    uitreenode(app.SensorsNode,'Text',app.C_SS_str);
                    app.n = 1;
                    app.n_2 = 1;
                    
                    expand(app.Tree);
                    
                case 'Fine Sun Sensor'                
                    
                    str = app.SensorsNode.Children;                   
                    while app.n <= size(str,1)
                        str_text = str(app.n).Text(1:end-2);
                        str_num = str(app.n).Text(end);
                        if strcmp(str_text,'Fine Sun Sersor')==1 && strcmp(str_num,num2str(app.n_2))==1
                            app.n_2 = app.n_2 + 1;
                            app.n = 1;                       
                        else
                        app.n = app.n + 1;
                        end
                    end
                    app.F_SS_str = ['Fine Sun Sersor ', num2str(app.n_2)];
                    
                    uitreenode(app.SensorsNode,'Text',app.F_SS_str);
                    app.n = 1;
                    app.n_2 = 1;
                    
                    expand(app.Tree);
                    
                case 'Magnetometer'
                             
                    str = app.SensorsNode.Children;                   
                    while app.n <= size(str,1)
                        str_text = str(app.n).Text(1:end-2);
                        str_num = str(app.n).Text(end);
                        if strcmp(str_text,'Magnetometer')==1 && strcmp(str_num,num2str(app.n_2))==1
                            app.n_2 = app.n_2 + 1;
                            app.n = 1;                       
                        else
                        app.n = app.n + 1;
                        end
                    end
                    app.Magnetometer_str = ['Magnetometer ', num2str(app.n_2)];
                    
                    uitreenode(app.SensorsNode,'Text',app.Magnetometer_str);
                    app.n = 1;
                    app.n_2 = 1;
                    
                    expand(app.Tree);                  
                    
                case 'Gyroscope'
                    
                    str = app.SensorsNode.Children;                   
                    while app.n <= size(str,1)
                        str_text = str(app.n).Text(1:end-2);
                        str_num = str(app.n).Text(end);
                        if strcmp(str_text,'Gyroscope')==1 && strcmp(str_num,num2str(app.n_2))==1
                            app.n_2 = app.n_2 + 1;
                            app.n = 1;                       
                        else
                        app.n = app.n + 1;
                        end
                    end
                    app.Gyro_str = ['Gyroscope ', num2str(app.n_2)];
                    
                    uitreenode(app.SensorsNode,'Text',app.Gyro_str);
                    app.n = 1;
                    app.n_2 = 1;
                    
                    expand(app.Tree);
                    
            end
        end

        % Value changed function: AvailableSensorsDropDown
        function AvailableSensorsDropDownValueChanged(app, event)
            %value_sensor_select = app.AvailableSensorsDropDown.Value;            
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            if strcmp(app.Tree.SelectedNodes.Text,'Sensors') ~= 1            
                arrayfun(@delete,app.Tree.SelectedNodes)
            else
                arrayfun(@delete,app.SensorsNode.Children)
            end
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            if strcmp(app.Tree.SelectedNodes.Text,app.C_SS_str) == 1
                C_SS_gui
            elseif strcmp(app.Tree.SelectedNodes.Text,app.F_SS_str) == 1
                F_SS_gui
            elseif strcmp(app.Tree.SelectedNodes.Text,app.Magnetometer_str) == 1    
                Magnetometer_gui
            elseif strcmp(app.Tree.SelectedNodes.Text,app.Gyro_str) == 1
                Gyro_gui
            end
        end

        % Button pushed function: RunSimulinkButton
        function RunSimulinkButtonPushed(app, event)
          global simout;
            evalin('base', 'Controller_gains_definition')
            simout = sim('Pitch_Sun_Track','TimeOut',app.stop_time);
        end

        % Value changed function: StopTimeEditField
        function StopTimeEditFieldValueChanged(app, event)
            app.stop_time = app.StopTimeEditField.Value;            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: rowanglevstimeCheckBox
        function rowanglevstimeCheckBoxValueChanged(app, event)
            value = app.rowanglevstimeCheckBox.Value;
            if value
                RollvsTime
            end
        end

        % Value changed function: pitchanglevstimeCheckBox
        function pitchanglevstimeCheckBoxValueChanged(app, event)
            value = app.pitchanglevstimeCheckBox.Value;
              if value
                PitchvsTime
            end
        end

        % Value changed function: yawanglevstimeCheckBox
        function yawanglevstimeCheckBoxValueChanged(app, event)
            value = app.yawanglevstimeCheckBox.Value;
             if value
                yawvstime
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainSystemTab = uitab(app.TabGroup);
            app.MainSystemTab.Title = 'Main System';
            app.RunSimulinkButton = uibutton(app.MainSystemTab, 'push');
            app.RunSimulinkButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulinkButtonPushed, true);
            app.RunSimulinkButton.Text = 'Run Simulink';
            app.StopTimeEditFieldLabel = uilabel(app.MainSystemTab);
            app.StopTimeEditFieldLabel.Text = 'Stop Time';
            app.StopTimeEditField = uieditfield(app.MainSystemTab, 'numeric');
            app.StopTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StopTimeEditFieldValueChanged, true);
            app.StopTimeEditField.Value = 200;
            app.LibraryTab = uitab(app.TabGroup);
            app.LibraryTab.Title = 'Library';
            app.AddSensorButton = uibutton(app.LibraryTab, 'push');
            app.AddSensorButton.ButtonPushedFcn = createCallbackFcn(app, @AddSensorButtonPushed, true);
            app.AddSensorButton.Text = 'Add Sensor';
            app.AddActuatorButton = uibutton(app.LibraryTab, 'push');
            app.AddActuatorButton.Text = 'Add Actuator';
            app.AvailableSensorsLabel = uilabel(app.LibraryTab);
            app.AvailableSensorsLabel.Text = 'Available Sensors:';
            app.AvailableSensorsDropDown = uidropdown(app.LibraryTab);
            app.AvailableSensorsDropDown.Items = {'Coarse Sun Sensor', 'Fine Sun Sensor', 'Gyroscope', 'Magnetometer'};
            app.AvailableSensorsDropDown.ValueChangedFcn = createCallbackFcn(app, @AvailableSensorsDropDownValueChanged, true);
            app.AvailableSensorsDropDown.Value = 'Coarse Sun Sensor';
            app.AvailableActuatorsDropDownLabel = uilabel(app.LibraryTab);
            app.AvailableActuatorsDropDownLabel.Text = 'Available Actuators:';
            app.AvailableActuatorsDropDown = uidropdown(app.LibraryTab);
            app.AvailableActuatorsDropDown.Items = {'Magnetorquer', 'Momentum Wheel', ''};
            app.AvailableActuatorsDropDown.Value = 'Magnetorquer';
            app.Tree = uitree(app.LibraryTab);
            app.SensorsNode = uitreenode(app.Tree);
            app.SensorsNode.Tag = 'Sensors';
            app.SensorsNode.Text = 'Sensors';
            app.Tree_2 = uitree(app.LibraryTab);
            app.ActuatorsNode = uitreenode(app.Tree_2);
            app.ActuatorsNode.Text = 'Actuators';
            app.Magnetorquer1Node = uitreenode(app.ActuatorsNode);
            app.Magnetorquer1Node.Text = 'Magnetorquer 1';
            app.Magnetorquer2Node = uitreenode(app.ActuatorsNode);
            app.Magnetorquer2Node.Text = 'Magnetorquer 2';
            app.Magnetorquer3Node = uitreenode(app.ActuatorsNode);
            app.Magnetorquer3Node.Text = 'Magnetorquer 3';
            app.MomentumWheel1Node = uitreenode(app.ActuatorsNode);
            app.MomentumWheel1Node.Text = 'Momentum Wheel 1';
            app.EditButton = uibutton(app.LibraryTab, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.RemoveButton = uibutton(app.LibraryTab, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.AlgorithmsTab = uitab(app.TabGroup);
            app.AlgorithmsTab.Title = 'Algorithms';
            app.AvailableDeterminationAlgorithmsLabel = uilabel(app.AlgorithmsTab);
            app.AvailableDeterminationAlgorithmsLabel.Text = 'Available Determination Algorithms:';
            app.AvailableControlAlgorithmsLabel = uilabel(app.AlgorithmsTab);
            app.AvailableControlAlgorithmsLabel.Text = 'Available Control Algorithms:';
            app.DropDown = uidropdown(app.AlgorithmsTab);
            app.DropDown.Items = {'Bang_Bang_2', 'Bang_Bang_1', 'PDI', 'PD'};
            app.DropDown.Value = 'Bang_Bang_2';
            app.DropDown_2 = uidropdown(app.AlgorithmsTab);
            app.DropDown_2.Items = {'EKF_1', 'Kalman_Filter', 'MFK', 'EKF_2'};
            app.DropDown_2.Value = 'EKF_1';
            app.AddDeterminationAlgorithmButton = uibutton(app.AlgorithmsTab, 'push');
            app.AddDeterminationAlgorithmButton.Text = 'Add Determination Algorithm';
            app.AddControlAlgorithmButton = uibutton(app.AlgorithmsTab, 'push');
            app.AddControlAlgorithmButton.Text = 'Add Control Algorithm';
            app.ViewDeterminationAlgorithmButton = uibutton(app.AlgorithmsTab, 'push');
            app.ViewDeterminationAlgorithmButton.Text = 'View Determination Algorithm';
            app.ViewDeterminationAlgorithmButton_2 = uibutton(app.AlgorithmsTab, 'push');
            app.ViewDeterminationAlgorithmButton_2.Text = 'View Determination Algorithm';
            app.ErrorTestingTab = uitab(app.TabGroup);
            app.ErrorTestingTab.Title = 'Error Testing (?)';
            app.PropagateTab = uitab(app.TabGroup);
            app.PropagateTab.Title = 'Propagate';
            app.IntegratorDropDownLabel = uilabel(app.PropagateTab);
            app.IntegratorDropDownLabel.Text = 'Integrator';
            app.IntegratorDropDown = uidropdown(app.PropagateTab);
            app.IntegratorDropDown.Items = {'RK2', 'RK4', 'AdamsBashford', 'AdamsMoulton'};
            app.IntegratorDropDown.Value = 'RK2';
            app.InitialtimestepEditFieldLabel = uilabel(app.PropagateTab);
            app.InitialtimestepEditFieldLabel.Text = 'Initial time step';
            app.InitialtimestepEditField = uieditfield(app.PropagateTab, 'numeric');
            app.timestepEditFieldLabel = uilabel(app.PropagateTab);
            app.timestepEditFieldLabel.Text = 'time step';
            app.timestepEditField = uieditfield(app.PropagateTab, 'numeric');
            app.timeintervalEditFieldLabel = uilabel(app.PropagateTab);
            app.timeintervalEditFieldLabel.Text = 'time interval';
            app.timeintervalEditField = uieditfield(app.PropagateTab, 'numeric');
            app.StartEpochEditFieldLabel = uilabel(app.PropagateTab);
            app.StartEpochEditFieldLabel.Text = 'Start Epoch';
            app.StartEpochEditField = uieditfield(app.PropagateTab, 'numeric');
            app.EpochFormatDropDownLabel = uilabel(app.PropagateTab);
            app.EpochFormatDropDownLabel.Text = 'Epoch Format';
            app.EpochFormatDropDown = uidropdown(app.PropagateTab);
            app.EpochFormatDropDown.Items = {'A1ModJulian', 'UTCGregorian', 'UTCModJulian', 'TDBModJulian'};
            app.EpochFormatDropDown.Value = 'A1ModJulian';
            app.secLabel = uilabel(app.PropagateTab);
            app.secLabel.Text = 'sec';
            app.secLabel_2 = uilabel(app.PropagateTab);
            app.secLabel_2.Text = 'sec';
            app.secLabel_3 = uilabel(app.PropagateTab);
            app.secLabel_3.Text = 'sec';
            app.PropagateButton = uibutton(app.PropagateTab, 'push');
            app.PropagateButton.Text = 'Propagate';
            app.ViewTab = uitab(app.TabGroup);
            app.ViewTab.Title = 'View';
            app.rowanglevstimeCheckBox = uicheckbox(app.ViewTab);
            app.rowanglevstimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @rowanglevstimeCheckBoxValueChanged, true);
            app.rowanglevstimeCheckBox.Text = 'row angle vs time';
            app.pitchanglevstimeCheckBox = uicheckbox(app.ViewTab);
            app.pitchanglevstimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @pitchanglevstimeCheckBoxValueChanged, true);
            app.pitchanglevstimeCheckBox.Text = 'pitch angle vs time';
            app.yawanglevstimeCheckBox = uicheckbox(app.ViewTab);
            app.yawanglevstimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @yawanglevstimeCheckBoxValueChanged, true);
            app.yawanglevstimeCheckBox.Text = 'yaw angle vs time';
            app.pitcherrorvstimeCheckBox_2 = uicheckbox(app.ViewTab);
            app.pitcherrorvstimeCheckBox_2.Text = 'pitch error vs time';
            app.rowerrorvstimeCheckBox = uicheckbox(app.ViewTab);
            app.rowerrorvstimeCheckBox.Text = 'row error vs time';
            app.yawerrorvstimeCheckBox = uicheckbox(app.ViewTab);
            app.yawerrorvstimeCheckBox.Text = 'yaw error vs time';
            app.rowerrorvstimemaxsensorerrorCheckBox = uicheckbox(app.ViewTab);
            app.rowerrorvstimemaxsensorerrorCheckBox.Text = 'row error vs time (max sensor error)';
            app.pitcherrorvstimemaxsensorerrorCheckBox = uicheckbox(app.ViewTab);
            app.pitcherrorvstimemaxsensorerrorCheckBox.Text = 'pitch error vs time (max sensor error)';
            app.yawerrorvstimemaxsensorerrorCheckBox = uicheckbox(app.ViewTab);
            app.yawerrorvstimemaxsensorerrorCheckBox.Text = 'yaw error vs time (max sensor error)';
            app.PlotLabel = uilabel(app.ViewTab);
            app.PlotLabel.Text = 'Plot:';
            app.Label = uilabel(app.ViewTab);
            app.Label.Text = '';
            app.ReferenceFrameViewDropDownLabel = uilabel(app.ViewTab);
            app.ReferenceFrameViewDropDownLabel.Text = 'Reference Frame View';
            app.ReferenceFrameViewDropDown = uidropdown(app.ViewTab);
            app.ReferenceFrameViewDropDown.Items = {'Body reference', 'Orbit reference', 'ECI reference frame', 'Sun centered Inertial frame'};
            app.ReferenceFrameViewDropDown.Value = 'Body reference';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end